classdef WeighingSystemGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        WeighagainButton       matlab.ui.control.Button
        WeightTextArea         matlab.ui.control.TextArea
        WeightTextAreaLabel    matlab.ui.control.Label
        READYButton            matlab.ui.control.Button
        SettingsTextArea       matlab.ui.control.TextArea
        SettingsTextAreaLabel  matlab.ui.control.Label
        ManualButton           matlab.ui.control.Button
        ProtocolTextArea       matlab.ui.control.TextArea
        ProtocolTextAreaLabel  matlab.ui.control.Label
        STARTButton            matlab.ui.control.Button
        SubjectTextArea        matlab.ui.control.TextArea
        SubjectTextAreaLabel   matlab.ui.control.Label
    end

    methods (Access = private)

        function updateWeightTextArea(app)
            file_path = 'C:\Users\laufs\Documents\GitHub\AutoPilot\StartButton\mean_value.txt';

            if isfile(file_path)
                mean_value = fileread(file_path);
                app.WeightTextArea.Value = mean_value;
                % Clear the file after reading its content
                fid = fopen(file_path, 'w');
                fclose(fid);
            else
                app.WeightTextArea.Value = 'File not found';
            end
        end

        function updateMouseIDTextArea(app)
            rfid_path = 'C:\Users\laufs\Documents\GitHub\AutoPilot\StartButton\RFID_value.txt';

            if isfile(rfid_path)
                rfid_value = fileread(rfid_path);
                app.SubjectTextArea.Value = rfid_value;
                % Clear the file after reading its content
                fid = fopen(rfid_path, 'w');
                fclose(fid);
            else
                app.SubjectTextArea.Value = 'File not found';
            end
        end

        function waitForFlagFile(~, flag_type)
            flag_file_path = ['C:\Users\laufs\Documents\GitHub\AutoPilot\StartButton\' flag_type '.txt'];

            while ~isfile(flag_file_path)
                pause(0.5);
            end

            delete(flag_file_path);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: READYButton
        function READYButtonPushed(app, event)
            % Create the start signal file to trigger the Python script
            start_signal_path = 'C:\Users\laufs\Documents\GitHub\AutoPilot\StartButton\start_signal.txt';
            fid = fopen(start_signal_path, 'w');
            if fid ~= -1
                fprintf(fid, 'start');
                fclose(fid);
                disp('Start signal created');
            else
                error('Unable to create start signal file');
            end

            % Wait for the Python script to process and write the RFID value
            %app.waitForFlagFile('ID_ready');
            %app.updateMouseIDTextArea();

            % Wait for the Python script to process and write the mean value
            app.WeightTextArea.Value = "Weighing in progress...";
            app.waitForFlagFile('mean_ready');
            app.updateWeightTextArea();
        end

        % Button pushed function: WeighagainButton
        function WeighagainButtonPushed(app, event)
            % Create the start signal file to trigger the Python script
            start_signal_path = 'C:\Users\laufs\Documents\GitHub\AutoPilot\StartButton\weigh_signal.txt';
            fid = fopen(start_signal_path, 'w');
            if fid ~= -1
                fprintf(fid, 'start');
                fclose(fid);
                disp('Start signal created');
            else
                error('Unable to create start signal file');
            end

            % Wait for the Python script to process and write the mean value
            app.WeightTextArea.Value = "Weighing in progress...";

            app.waitForFlagFile('mean_ready');
            app.updateWeightTextArea();
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            global BpodSystem % Import the global BpodSystem object
            BpodSystem.GUIHandles.rfidGUI = WeighingSystemGUI;
            protocolName = char(BpodSystem.GUIHandles.rfidGUI.ProtocolTextArea.Value);
            subjectName = char(BpodSystem.GUIHandles.rfidGUI.SubjectTextArea.Value);
            settingsName = char(BpodSystem.GUIHandles.rfidGUI.SettingsTextArea.Value);
            settingsFileName = fullfile(BpodSystem.Path.DataFolder, subjectName, protocolName, 'Session Settings', [settingsName '.mat']);
            dataFolder = fullfile(BpodSystem.Path.DataFolder,subjectName,protocolName,'Session Data');
            if ~exist(dataFolder)
                mkdir(dataFolder);
            end

            % On Bpod r2+, if FlexIO channels are configured as analog,
            % setup binary data file
            if BpodSystem.MachineType > 3
                nAnalogChannels = sum(BpodSystem.HW.FlexIO_ChannelTypes == 2);
                if nAnalogChannels > 0
                    analogFilename = [BpodSystem.Path.CurrentDataFile(1:end-4) '_ANLG.dat'];
                    if BpodSystem.Status.RecordAnalog == 1
                        BpodSystem.AnalogDataFile = fopen(analogFilename,'w');
                        if BpodSystem.AnalogDataFile == -1
                            error(['Error: Could not open the analog data file: ' analogFilename])
                        end
                    end
                    BpodSystem.Status.nAnalogSamples = 0;
                end
            end

            BpodSystem.Status.Live = 1;
            BpodSystem.Status.LastEvent = 0;
            BpodSystem.GUIData.ProtocolName = protocolName;
            BpodSystem.GUIData.SubjectName = subjectName;
            BpodSystem.GUIData.SettingsFileName = settingsFileName;
            BpodSystem.Path.Settings = settingsFileName;
            settingStruct = load(BpodSystem.Path.Settings);
            F = fieldnames(settingStruct);
            fieldName = F{1};
            BpodSystem.ProtocolSettings = eval(['settingStruct.' fieldName]);
            BpodSystem.Data = struct;
            if BpodSystem.MachineType > 3
                if nAnalogChannels > 0
                    BpodSystem.Data.Analog = struct;
                    BpodSystem.Data.Analog.info = struct;
                    BpodSystem.Data.Analog.FileName = analogFilename;
                    BpodSystem.Data.Analog.nChannels = nAnalogChannels;
                    BpodSystem.Data.Analog.channelNumbers = find(BpodSystem.HW.FlexIO_ChannelTypes == 2);
                    BpodSystem.Data.Analog.SamplingRate = BpodSystem.HW.FlexIO_SamplingRate;
                    BpodSystem.Data.Analog.nSamples = 0;
                    % Add human-readable info about data fields to 'info struct
                    BpodSystem.Data.Analog.info.FileName = 'Complete path and filename of the binary file to which the raw data was logged';
                    BpodSystem.Data.Analog.info.nChannels = 'The number of Flex I/O channels configured as analog input';
                    BpodSystem.Data.Analog.info.channelNumbers = 'The indexes of Flex I/O channels configured as analog input';
                    BpodSystem.Data.Analog.info.SamplingRate = 'The sampling rate of the analog data. Units = Hz';
                    BpodSystem.Data.Analog.info.nSamples = 'The total number of analog samples captured during the behavior session';
                    BpodSystem.Data.Analog.info.Samples = 'Analog measurements captured. Rows are separate analog input channels. Units = Volts';
                    BpodSystem.Data.Analog.info.Timestamps = 'Time of each sample (computed from sample index and sampling rate)';
                    BpodSystem.Data.Analog.info.TrialNumber = 'Experimental trial during which each analog sample was captured';
                    BpodSystem.Data.Analog.info.TrialData = 'A cell array of Samples. Each cell contains samples captured during a single trial.';
                end
            end
            protocolFolderPath = fullfile(BpodSystem.Path.ProtocolFolder,protocolName);
            protocolPath = fullfile(BpodSystem.Path.ProtocolFolder,protocolName,[protocolName '.m']);
            addpath(protocolFolderPath);
            set(BpodSystem.GUIHandles.RunButton, 'cdata', BpodSystem.GUIData.PauseButton, 'TooltipString', 'Press to pause session');

            % % Send metadata to Bpod Phone Home program (disabled pending a more stable server)
            % isOnline = BpodSystem.check4Internet();
            % if (isOnline == 1) && (BpodSystem.SystemSettings.PhoneHome == 1)
            %     BpodSystem.BpodPhoneHome(1);
            % end

            if BpodSystem.Status.AnalogViewer
                set(BpodSystem.GUIHandles.RecordButton, 'Enable', 'off')
            end

            BpodSystem.Status.BeingUsed = 1;
            BpodSystem.Status.SessionStartFlag = 1;
            BpodSystem.ProtocolStartTime = now*100000;
            BpodSystem.resetSessionClock();
            close(BpodSystem.GUIHandles.rfidGUI.UIFigure);
            %close (BpodSystem.GUIHandles.LaunchManagerFig);
            disp(' ');
            disp(['Starting ' protocolName]);
            set(BpodSystem.GUIHandles.CurrentStateDisplay, 'String', '---');
            set(BpodSystem.GUIHandles.PreviousStateDisplay, 'String', '---');
            set(BpodSystem.GUIHandles.LastEventDisplay, 'String', '---');
            set(BpodSystem.GUIHandles.TimeDisplay, 'String', '0:00:00');
            if sum(BpodSystem.InputsEnabled(BpodSystem.HW.Inputs == 'P')) == 0
                warning(['All Bpod behavior ports are currently disabled.'...
                    'If your protocol requires behavior ports, enable them from the settings menu.'])
            end
            run(protocolPath);
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            LaunchManager;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectTextAreaLabel = uilabel(app.UIFigure);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.UIFigure);
            app.SubjectTextArea.Editable = 'off';
            app.SubjectTextArea.Value = {'FakeSubject'};
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ProtocolTextAreaLabel = uilabel(app.UIFigure);
            app.ProtocolTextAreaLabel.Text = 'Protocol';
            app.ProtocolTextArea = uitextarea(app.UIFigure);
            app.ProtocolTextArea.Editable = 'off';
            app.ProtocolTextArea.Value = {'LickingLama'};
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.SettingsTextAreaLabel = uilabel(app.UIFigure);
            app.SettingsTextAreaLabel.Text = 'Settings';
            app.SettingsTextArea = uitextarea(app.UIFigure);
            app.SettingsTextArea.Editable = 'off';
            app.SettingsTextArea.Value = {'DefaultSettings'};
            app.READYButton = uibutton(app.UIFigure, 'push');
            app.READYButton.ButtonPushedFcn = createCallbackFcn(app, @READYButtonPushed, true);
            app.READYButton.Text = 'READY';
            app.WeightTextAreaLabel = uilabel(app.UIFigure);
            app.WeightTextAreaLabel.Text = 'Weight';
            app.WeightTextArea = uitextarea(app.UIFigure);
            app.WeightTextArea.Editable = 'off';
            app.WeighagainButton = uibutton(app.UIFigure, 'push');
            app.WeighagainButton.ButtonPushedFcn = createCallbackFcn(app, @WeighagainButtonPushed, true);
            app.WeighagainButton.Text = 'Weigh again';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WeighingSystemGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end